classdef image_page < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        BackButton          matlab.ui.control.Button
        CompareButton       matlab.ui.control.Button
        InterpreationLabel  matlab.ui.control.Label
        ZigZag_readButton   matlab.ui.control.Button
        Vertic_readButton   matlab.ui.control.Button
        Horiz_readButton    matlab.ui.control.Button
        Take_pictureButton  matlab.ui.control.Button
        EditField           matlab.ui.control.EditField
        Image               matlab.ui.control.Image
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Take_pictureButton
        function Take_pictureButtonPushed(app, event)
            global squareImage;
            [fileName, pathName] = uigetfile({'*.jpg;*.png;*.bmp'}, 'Select an Image File');
            if ~isequal(fileName, 0)
                filePath = fullfile(pathName, fileName);
                squareImage = convertToSquare(filePath);
                %image = imread(filePath);
                imshow(squareImage,'Parent',app.UIAxes);
            end
        end

        % Button pushed function: Vertic_readButton
        function Vertic_readButtonPushed(app, event)
            global squareImage;
            verticallyFlippedImage = flipud(squareImage);
            imshow(verticallyFlippedImage,'Parent',app.UIAxes2);
            title(app.UIAxes2, 'Vertical read');
        end

        % Button pushed function: Horiz_readButton
        function Horiz_readButtonPushed(app, event)
            global squareImage;
            horizontallyFlippedImage = fliplr(squareImage);
            imshow(horizontallyFlippedImage,'Parent',app.UIAxes2);
            title(app.UIAxes2, 'horizantal read');
        end

        % Button pushed function: CompareButton
        function CompareButtonPushed(app, event)
            global squareImage;
            result1=vertical_read(squareImage);
            result2=horizontal_scan(squareImage);
            result3=zigzag_scan(squareImage);
            if result1 >= result2 && result1 >= result3
                newText = ["interpretation:", ' ', "the one with more zeros is the vertical one"];
                app.InterpreationLabel.Text=newText;
            elseif result2 >= result1 && result2 >= result3
                newText = ["interpretation", ' ', "the one with more zeros is the horizantal one"];
                app.InterpreationLabel.Text=newText;
            else
                newText = ["interpretation", ' ', "the one with more zeros is the zigzag one"];
                app.InterpreationLabel.Text=newText;
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.UIFigure.Visible="off";
            main_app
        end

        % Button pushed function: ZigZag_readButton
        function ZigZag_readButtonPushed(app, event)
            global squareImage;
            im=rgb2gray(squareImage);
            size_v=length(im);
            out=my_zigzag(im);
            out2=invzigzag(out,size_v,size_v);
            imshow(out2,'Parent',app.UIAxes2);
            title(app.UIAxes2, 'zigzag read');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original_Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Modified_image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'header.jpg';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'PROJECT COMPRESSION CODING';
            app.Take_pictureButton = uibutton(app.UIFigure, 'push');
            app.Take_pictureButton.ButtonPushedFcn = createCallbackFcn(app, @Take_pictureButtonPushed, true);
            app.Take_pictureButton.Text = 'Take_picture';
            app.Horiz_readButton = uibutton(app.UIFigure, 'push');
            app.Horiz_readButton.ButtonPushedFcn = createCallbackFcn(app, @Horiz_readButtonPushed, true);
            app.Horiz_readButton.Text = 'Horiz_read';
            app.Vertic_readButton = uibutton(app.UIFigure, 'push');
            app.Vertic_readButton.ButtonPushedFcn = createCallbackFcn(app, @Vertic_readButtonPushed, true);
            app.Vertic_readButton.Text = 'Vertic_read';
            app.ZigZag_readButton = uibutton(app.UIFigure, 'push');
            app.ZigZag_readButton.ButtonPushedFcn = createCallbackFcn(app, @ZigZag_readButtonPushed, true);
            app.ZigZag_readButton.Text = 'ZigZag_read';
            app.InterpreationLabel = uilabel(app.UIFigure);
            app.InterpreationLabel.Tag = 'my_text';
            app.InterpreationLabel.Text = 'Interpreation:';
            app.CompareButton = uibutton(app.UIFigure, 'push');
            app.CompareButton.ButtonPushedFcn = createCallbackFcn(app, @CompareButtonPushed, true);
            app.CompareButton.Text = 'Compare';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = image_page

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end