classdef text_page < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        BackButton                   matlab.ui.control.Button
        StartButton                  matlab.ui.control.Button
        EditField_4                  matlab.ui.control.EditField
        EditField_3                  matlab.ui.control.EditField
        EditField_2                  matlab.ui.control.EditField
        EnteryourtextEditField       matlab.ui.control.EditField
        EnteryourtextEditFieldLabel  matlab.ui.control.Label
        EditField                    matlab.ui.control.EditField
        Image                        matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %this part read from the input text and pass to the function
            %predefined compressionRatio_shannon
            text=app.EnteryourtextEditField.Value;
            %compressionRatio = compressionRatio_huffman(char(text));
            compressionRatio=compressionRatio_huffman(char(text));
            compressionRatio2 = compressionRatio_shannon(char(text));
            app.EditField_2.Value=strcat("RAT_Compr_HUFFMAN:",num2str(compressionRatio));
            app.EditField_3.Value=strcat("RAT_Compr_Shanon-Fano:",num2str(compressionRatio2));
            if(compressionRatio < compressionRatio2)
                app.EditField_4.Visible="on";
                app.EditField_4.Value="Huffamn compression is the best in this case";
            end
            if(compressionRatio==compressionRatio2)
                app.EditField_4.Visible="on";
                app.EditField_4.Value="there is no difference between the both methods since the huffman is optimisation of shannon";
            end


        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.UIFigure.Visible="off";
            main_app
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'header.jpg';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'PROJECT COMPRESSION CODING';
            app.EnteryourtextEditFieldLabel = uilabel(app.UIFigure);
            app.EnteryourtextEditFieldLabel.Text = 'Enter your text:';
            app.EnteryourtextEditField = uieditfield(app.UIFigure, 'text');
            app.EnteryourtextEditField.Tag = 'my_text';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Tag = 'text_huff';
            app.EditField_2.Value = 'RAT_Compr_HUFFMAN';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Tag = 'text_huff';
            app.EditField_3.Value = 'RAT_Compr_Shanon-Fano';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.Visible = 'off';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Tag = 'btn';
            app.StartButton.Text = 'Start';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = text_page

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end